classdef RobotController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ReturnPenButton       matlab.ui.control.Button
        PickPenButton         matlab.ui.control.Button
        DrawFuButton          matlab.ui.control.Button
        PassGatesButton       matlab.ui.control.Button
        JacobianButton        matlab.ui.control.Button
        ReadCurrentPosButton  matlab.ui.control.Button
        VelocityModeButton    matlab.ui.control.Button
        PositionModeButton    matlab.ui.control.Button
        PickwithAngleButton   matlab.ui.control.Button
        PickandPutButton      matlab.ui.control.Button
        PutButton             matlab.ui.control.Button
        PickButton            matlab.ui.control.Button
        Z_PutEditField        matlab.ui.control.NumericEditField
        Z_PutEditFieldLabel   matlab.ui.control.Label
        Y_PutEditField        matlab.ui.control.NumericEditField
        Y_PutEditFieldLabel   matlab.ui.control.Label
        X_PutEditField        matlab.ui.control.NumericEditField
        X_PutEditFieldLabel   matlab.ui.control.Label
        Z_PickEditField       matlab.ui.control.NumericEditField
        Z_PickEditFieldLabel  matlab.ui.control.Label
        Y_PickEditField       matlab.ui.control.NumericEditField
        Y_PickEditFieldLabel  matlab.ui.control.Label
        X_PickEditField       matlab.ui.control.NumericEditField
        X_PickEditFieldLabel  matlab.ui.control.Label
        GotowithAngleButton   matlab.ui.control.Button
        AngleWEditField       matlab.ui.control.NumericEditField
        AngleWEditFieldLabel  matlab.ui.control.Label
        GripperReleaseButton  matlab.ui.control.Button
        GripperHoldButton     matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
        PointZEditField       matlab.ui.control.NumericEditField
        PointZEditFieldLabel  matlab.ui.control.Label
        Y_GridEditField       matlab.ui.control.NumericEditField
        Y_GridEditFieldLabel  matlab.ui.control.Label
        X_GridEditField       matlab.ui.control.NumericEditField
        X_GridEditFieldLabel  matlab.ui.control.Label
        GotoPointButton       matlab.ui.control.Button
        RunButton             matlab.ui.control.Button
        DisconnectButton      matlab.ui.control.Button
        ConnectButton         matlab.ui.control.Button
        EditField5            matlab.ui.control.NumericEditField
        EditField5Label       matlab.ui.control.Label
        Slider5               matlab.ui.control.Slider
        Slider5Label          matlab.ui.control.Label
        EditField4            matlab.ui.control.NumericEditField
        EditField4Label       matlab.ui.control.Label
        Slider4               matlab.ui.control.Slider
        Slider4Label          matlab.ui.control.Label
        EditField3            matlab.ui.control.NumericEditField
        EditField3Label       matlab.ui.control.Label
        Slider3               matlab.ui.control.Slider
        Slider3Label          matlab.ui.control.Label
        EditField2            matlab.ui.control.NumericEditField
        EditField2Label       matlab.ui.control.Label
        Slider2               matlab.ui.control.Slider
        Slider2Label          matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
        SliderLabel           matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lib_name
        port_num
        PROTOCOL_VERSION = 2.0
        BAUDRATE = 1000000
        DEVICENAME = "COM3"
    
        % IDs
        DXL_ID1 = 11
        DXL_ID2 = 12
        DXL_ID3 = 13
        DXL_ID4 = 14
        DXL_GRIP = 15
        DXL_IDS

        L1_global = 0.077
        L2_global = 0.130
        L3_global = 0.124
        L4_global = 0.145
    
        % addresses
        ADDR_MAX_POS = 48
        ADDR_MIN_POS = 52
        ADDR_PRO_TORQUE_ENABLE = 64
        ADDR_PRO_GOAL_POSITION = 116
        ADDR_PRO_PRESENT_POSITION = 132
        ADDR_PRO_OPERATING_MODE = 11
        ADDR_PRO_PROFILE_VELOCITY = 112
        ADDR_PRO_GOAL_VELOCITY = 104
        ADDR_PRO_PROFILE_ACCELERATION = 108
    
        % constants
        TORQUE_ENABLE = 1
        TORQUE_DISABLE = 0
        MAX_POS = 3400
        MIN_POS = 600
        VELOCITY = 30
        ACCEL = 100
        VELOCITY_DEG = 50
        ACCEL_DEG = 500
        proportionalConstant = 0.1

        theta_limit_deg = [-120,120;...
                            -90,90;...
                            -90,90;...
                            -115,105;
                            -90,90]

    
        isConnected = false
        updateTimer
        
        thetaOffset = [deg2rad(0), pi/2-17.5*pi/180, -pi/2+17.5*pi/180, 0];
        thetaSign = [1 -1 -1 -1 1];  % 每个关节的方向（第5个是夹爪，先不反）
        theta_current = [0,0,0,0,0];
        
        GRIP_HOLD_POS = 23;
        GRIP_RELEASE_POS = -45;
        GRIP_BRIDGE_POS = -5;

        draw_enable = false;

        hArmLine;
        hGripperTrace;
        gripperTraceData;
        hRealTrace;
        realTraceData;

        vmax_deg = 60;
        alpha_plan_deg = 180;
        alpha_lim_deg = 180;

        sync_omega_max = [0,0,0,0]
        sync_alpha_max = [0,0,0,0]
        vel_last = [0,0,0,0]
        theta_tolerance = 0.5
        vel_tolerance = 0.5
        qdot_max_degps = 200
        dls_lambda = 0.01
        p_tolerance = 2e-3
        alpha_vel_degps2 = 500
        v_const_cart = 0.1
        
        phi_list_down
        phi_list_simple
        last_pick_angle = -90

        gate_height = 0.2;
    end

  
    methods (Access = private)
            function redraw(app)
                % 读角度（度->弧度）
                th_deg = [app.EditField.Value, ...
                          app.EditField2.Value, ...
                          app.EditField3.Value, ...
                          app.EditField4.Value,];
                th = deg2rad(th_deg);
            
                % FK 得到点
                P = app.fk_points(th);  % 3x5
                %disp("Wrist Position: ")
                %disp(P(:,4))
                gripper = P(:,5).';   % 1x3

                % 记录轨迹
                app.gripperTraceData = [app.gripperTraceData; gripper];

                % 画图
                %cla(app.UIAxes);
                set(app.hArmLine, 'XData', P(1,:), 'YData', P(2,:), 'ZData', P(3,:));

                % 更新红色轨迹
                if(app.draw_enable)
                    set(app.hGripperTrace, ...
                        'XData', app.gripperTraceData(:,1), ...
                        'YData', app.gripperTraceData(:,2), ...
                        'ZData', app.gripperTraceData(:,3));
                end

            end

            function redraw_pos(app, P)
                gripper = P(:,5).';   % 1x3

                % 记录轨迹
                app.gripperTraceData = [app.gripperTraceData; gripper];

                % 画图
                %cla(app.UIAxes);
                set(app.hArmLine, 'XData', P(1,:), 'YData', P(2,:), 'ZData', P(3,:));

                % 更新红色轨迹
                if(app.draw_enable)
                    set(app.hGripperTrace, ...
                        'XData', app.gripperTraceData(:,1), ...
                        'YData', app.gripperTraceData(:,2), ...
                        'ZData', app.gripperTraceData(:,3));
                end

            end


            % ===== 通用回调：Slider拖动时 =====
            function ThetaSliderChanging(app, event, idx)
                val = event.Value;
                app.setTheta(idx, val, true);  % true=来自滑动条
            end
        
            % ===== 通用回调：Slider松手/或EditField改值 =====
            function ThetaControlChanged(app, idx, val)

                app.setTheta(idx, val, false);
            end


            function v = proportionalVel(app, theta, thetaf)
                v = app.proportionalConstant*(thetaf-theta);
            end


            function name = getLibName(~)
                if strcmp(computer,'PCWIN')
                    name = 'dxl_x86_c';
                elseif strcmp(computer,'PCWIN64')
                    name = 'dxl_x64_c';
                elseif strcmp(computer,'GLNX86')
                    name = 'libdxl_x86_c';
                elseif strcmp(computer,'GLNXA64')
                    name = 'libdxl_x64_c';
                elseif strcmp(computer,'MACI64')
                    name = 'libdxl_mac_c';
                else
                    error("Unsupported platform: %s", computer);
                end
            end

            function connectDxl(app)
                if app.isConnected
                    return;
                end
            
                app.lib_name = app.getLibName();
            
                % Load Libraries
                if ~libisloaded(app.lib_name)
                    loadlibrary(app.lib_name, 'dynamixel_sdk.h', ...
                        'addheader','port_handler.h', ...
                        'addheader','packet_handler.h');
                end
            
                % port/packet init
                app.port_num = portHandler(char(app.DEVICENAME));
                packetHandler();
            
                % Open port
                if ~openPort(app.port_num)
                    error("Failed to open port %s", app.DEVICENAME);
                end
            
                % Set baudrate
                if ~setBaudRate(app.port_num, app.BAUDRATE)
                    error("Failed to set baudrate");
                end
            
                % Disable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                end
            
                % Position control mode
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 3);
                end

                % for id = app.DXL_IDS
                %     write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 1);
                % end
            
                % Enable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_ENABLE);
                end

                % Set Speed
                for id = app.DXL_IDS(1:4)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_PROFILE_VELOCITY, app.VELOCITY);
                end
                write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 15, app.ADDR_PRO_PROFILE_VELOCITY, 1000);

                %Set Acceleration
                for id = app.DXL_IDS(1:4)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_PROFILE_ACCELERATION, app.ACCEL);
                end
                write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 15, app.ADDR_PRO_PROFILE_ACCELERATION, 0);

            
                % Limits + center
                for id = app.DXL_IDS
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_MAX_POS, app.MAX_POS);
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_MIN_POS, app.MIN_POS);
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, 2048);
                end
                pause(0.5);
            
                app.isConnected = true;
            end

            function disconnectDxl(app)
                if ~app.isConnected
                    return;
                end
            
                % stop timer first
                if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                    stop(app.updateTimer);
                end
            
                % Disable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                end
            
                % Close port
                closePort(app.port_num);
            
                % Unload library
                if libisloaded(app.lib_name)
                    unloadlibrary(app.lib_name);
                end
            
                app.isConnected = false;
            end
           
            function onTimerTick(app)
                if ~app.isConnected
                    return;
                end
            
            
                %goal = app.deg2pos(th_deg);
            
                % 2) 写入五个电机
                ids = app.DXL_IDS;
                %for k = 1:numel(ids)
                %    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_POSITION, goal(k));
                %end
            
                % 3) 读回位置（用于显示/3D）
                pos = zeros(1,numel(ids));
                for k = 1:numel(ids)
                    pos(k) = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION);
                end
            
                % 4) 用前4个关节做 FK（你给的 DH 表是4轴）
                theta_rad = app.pos2rad(pos(1:4));
                P = app.fk_points(theta_rad);   % 用我前面给你的 DH FK 函数
            
                cla(app.UIAxes);
                plot3(app.UIAxes, P(1,:),P(2,:),P(3,:), '-o','LineWidth',2);
                grid(app.UIAxes,'on'); axis(app.UIAxes,'equal'); view(app.UIAxes,3);
                drawnow limitrate;
            end
            
            function th = pos2rad(~, pos)
                th = (double(pos) - 2048) / 4096 * 2*pi;
            end
            
            function pos = rad2pos(~, th)
                pos = round(2048 + th/(2*pi)*4096);
            end
            
            function pos = deg2pos(app, th_deg)
                th = deg2rad(th_deg);
                pos = app.rad2pos(th);
            
                % 安全限幅（可选）
                pos = max(app.MIN_POS, min(app.MAX_POS, pos));
            end

            function deg = pos2deg(app, pos)
                th = app.pos2rad(pos);
                deg = rad2deg(th);
            end

            function pts = interp(~, p1,p2,N)
                t = linspace(0,1,N)';
                pts = p1.*(1-t) + p2.*t;
            end

            function drawSquare(app)
                traj = app.calcSquarePts();
            
            
                % ===== 初值：用当前UI角度（用于连续性/平滑）=====
                %last = deg2rad([app.EditField.Value, app.EditField2.Value, app.EditField3.Value, app.EditField4.Value]);
                
                for k = 1:size(traj,1)
                    %disp("Traj")
                    %disp(traj(k,:))
                    xd = traj(k,1);
                    yd = traj(k,2);
                    zd = traj(k,3);

                    GotoPoint(app, xd, yd, zd);

                    pause(0.03);

                    if app.isConnected
                        p_real = app.readRealWristXYZ();          % 1x3
                        app.realTraceData = [app.realTraceData; p_real];
                    
                        set(app.hRealTrace, ...
                            'XData', app.realTraceData(:,1), ...
                            'YData', app.realTraceData(:,2), ...
                            'ZData', app.realTraceData(:,3));
                    end

                end
            end

            function squarePts = calcSquarePts(app)
                % ====== 你在这里改参数 ======
                center = [grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value)];   % 方形中心 (x,y) 单位 m
                side   = 0.1;           % 边长 (m) 例如 8cm
                z      = app.PointZEditField.Value;           % 高度 (m)
                n      = 40;             % 每条边点数：20
            
                cx = center(1); cy = center(2);
                h  = side/2;
            
                % 四个角（逆时针）
                p1 = [cx-h, cy-h, z];
                p2 = [cx+h, cy-h, z];
                p3 = [cx+h, cy+h, z];
                p4 = [cx-h, cy+h, z];
            
                % 每条边 n 个点
                e1 = linspace3(app, p1, p2, n);
                e2 = linspace3(app, p2, p3, n);
                e3 = linspace3(app, p3, p4, n);
                e4 = linspace3(app, p4, p1, n);
            
                % 拼接，去掉重复角点（每段第一点=上一段最后一点）
                squarePts = [e1;
                             e2(2:end,:);
                             e3(2:end,:);
                             e4(2:end,:)];
            end
            
            function P = linspace3(~, p0, p1, n)
                t = linspace(0,1,n).';
                P = p0 + (p1 - p0).*t;
            end

            function val_lim = setTheta_sim(app, idx, val, ~)

                app.theta_current(idx) = val;
                val = max(app.theta_limit_deg(idx,1), min(val, app.theta_limit_deg(idx,2)));           
                % 2) 同步 slider <-> editfield
                switch idx
                    case 1
                        app.Slider.Value  = val; app.EditField.Value  = val;
                    case 2
                        app.Slider2.Value = val; app.EditField2.Value = val;
                    case 3
                        app.Slider3.Value = val; app.EditField3.Value = val;
                    case 4
                        app.Slider4.Value = val; app.EditField4.Value = val;
                    case 5
                        app.Slider5.Value = val; app.EditField5.Value = val;
                end
            
                app.redraw();

                val_lim = val;
            end

            function setTheta(app, idx, val, ~)

                val = app.setTheta_sim(idx, val, false);
            
                % 4) 再驱动实物（只有已连接才写）
                if ~app.isConnected
                    return;
                end

                id = app.DXL_IDS(idx);
            
                % 6) idx -> 真正的电机ID（推荐用数组映射，别用 idx+10 猜）
                val_corr = app.thetaSign(idx) * val;

                % 7) 度 -> position
                goal_pos = app.deg2pos(val_corr);

                % 8) 写入电机（加 try/catch 防止异常卡UI）
                try
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, goal_pos);
                    if(idx == 4)
                        disp(goal_pos)
                    end
                catch ME
                    % 写失败就断开标记，避免后续一直卡
                    disp(ME.message);
                    app.isConnected = false;
                end
            end


            function setTheta_sync(app, idx, val, ~, v_max, a_max)

                %fprintf("Moving motor %d with vel: %.3f, accel: %.3f\n", idx, v_max, a_max);
                val = app.setTheta_sim(idx, val, false);

                if ~app.isConnected
                    return;
                end

                %更改profile速度
                id = app.DXL_IDS(idx);
                app.set_profile_velocity_degps(id, v_max);
                app.set_profile_acceleration_degps2(id, a_max);
                %fprintf("Motor %d Moving with speed: %d, accel %d\n", idx, v_max, a_max);
            
                % 6) idx -> 真正的电机ID（推荐用数组映射，别用 idx+10 猜）
                   % [11 12 13 14 15]
                val_corr = app.thetaSign(idx) * val;

                % 7) 度 -> position
                goal_pos = app.deg2pos(val_corr);

                % 8) 写入电机（加 try/catch 防止异常卡UI）
                try
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, goal_pos);
                catch ME
                    % 写失败就断开标记，避免后续一直卡
                    disp(ME.message);
                    app.isConnected = false;
                end
            end

            function [v_out, a_out] = ratio(~, s_max, s_c, v_max, a_max)
                r = abs(s_c)/abs(s_max);
                v_out = v_max*r;
                a_out = a_max*r;
            end

            function set_group_v_a_deg(app, velocity, acceleration)
                ids = app.DXL_IDS(1:4);
                for k = 1:numel(ids)
                    app.set_profile_velocity_degps(ids(k), velocity(k));
                    app.set_profile_acceleration_degps2(ids(k), acceleration(k));
                end
            end

            function set_group_theta(app, theta_f)

                ids = app.DXL_IDS(1:4);
                theta_f_lim = zeros(1,4);

                for index = 1:numel(ids)
                    theta_f_lim(index) = app.setTheta_sim(index, theta_f(index), false);
                end  

                v_sync = zeros(1,4);
                a_sync = zeros(1,4);

                start_pos = app.read_all_current_pos();
                distance = theta_f_lim-start_pos;

                abs_distance = abs(distance);

                Dmax = max(abs_distance);
                if Dmax < 1e-6
                    return
                end
                
                for index = 1:numel(ids)
                    [v_sync(index), a_sync(index)] = app.ratio(Dmax, distance(index), app.VELOCITY_DEG, app.ACCEL_DEG);
                end
                
                app.set_group_v_a_deg(v_sync, a_sync);

                theta_f_corr = app.thetaSign(1:4) .* theta_f_lim;

                for index = 1:numel(ids)
                    goal_pos = app.deg2pos(theta_f_corr(index));
                    try
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(index), app.ADDR_PRO_GOAL_POSITION, goal_pos);
                    catch ME
                        % 写失败就断开标记，避免后续一直卡
                        disp(ME.message);
                        app.isConnected = false;
                    end
                end
            end

            function theta_ui = motorRad2uiRad(app, theta_motor)
                % motor = sign*ui - offset
                % => ui = (motor + offset) ./ sign
                theta_ui = (theta_motor) ./ app.thetaSign(1:4);
            end

            function p_wrist = readRealWristXYZ(app)
                % 读4个关节当前位置（Dynamixel position）
                ids = app.DXL_IDS(1:4);
            
                pos = zeros(1,4);
                for k = 1:4
                    pos(k) = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION);
                end
            
                % position -> theta_motor(rad)
                theta_motor = app.pos2rad(pos);     % 1x4 rad（电机角，含实际方向）
            
                % motor角 -> UI角（去掉 sign/offset，保证跟 fk_T_all 输入一致）
                theta_ui = app.motorRad2uiRad(theta_motor);
            
                % 用FK算点
                P = app.fk_points(theta_ui);   % 3x5
            
                % 腕点 = 第3节末端 = P(:,4)
                p_wrist = P(:,4).';            % 1x3
            end

            function theta1 = readTheta1(app)
                if(app.isConnected)
                    theta1 = app.pos2deg(-read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 11, app.ADDR_PRO_PRESENT_POSITION));
                else
                    theta1 = 0;
                end
            end

            function gripper_hold(app)
                setTheta(app, 5, app.GRIP_HOLD_POS);
                pause(0.2);
            end

            function gripper_release(app, bridge)
                if nargin < 2
                    bridge = false;
                end

                if(bridge)
                    gripper_release_pos = app.GRIP_BRIDGE_POS;
                else
                    gripper_release_pos = app.GRIP_RELEASE_POS;
                end

                setTheta(app, 5, gripper_release_pos);
                pause(0.2);
            end

            function real_pos = grid2pos(~, grid_pos)
                real_pos = grid_pos/7.5*0.215;
            end

            function grid_pos = pos2grid(~, real_pos)
                grid_pos = real_pos/0.215*7.5;
            end

            function set_position_mode(app)
                if(app.isConnected)
                    for id = app.DXL_IDS(1:4)
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                    end
                    for id = app.DXL_IDS(1:4)
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 3);
                    end
                    for id = app.DXL_IDS(1:4)
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_ENABLE);
                    end
                    disp("Switched to Position Control Mode");
                else
                    disp("Not Connected")
                end
            end

            function set_velocity_mode(app)
                if(app.isConnected)
                    for id = app.DXL_IDS(1:4)
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                    end
                    for id = app.DXL_IDS(1:3)
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 1);
                    end
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 4, app.ADDR_PRO_OPERATING_MODE, 3);
                    for id = app.DXL_IDS(1:4)
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_ENABLE);
                    end
                    disp("Switched to Velocity Control Mode");
                else
                    disp("Not Connected")
                end
            end


            function angle_rad = GotoPoint(app, pos_x, pos_y, pos_z, phi_list, test)
                if nargin < 6
                    test = false;
                end
                for phi = phi_list
                    GotoPoint_out = app.GotoPoint_Angle(pos_x,pos_y,pos_z,phi,test);
                    if(GotoPoint_out(1))
                        angle_rad = phi;
                        return;
                    end
                end
                angle_rad = NaN;
            end

            function angle_rad = GotoPoint_variable_z(app, pos_x, pos_y, pos_z, phi_list, test)
                if nargin < 6
                    test = false;
                end
                for phi = phi_list
                    
                    GotoPoint_out = app.GotoPoint_Angle(pos_x,pos_y,pos_z,phi,test);
                    if(GotoPoint_out(1))
                        angle_rad = phi;
                        return;
                    end
                end
                angle_rad = NaN;
            end

            function GotoPoint_k_points(app, pos_x, pos_y, pos_z, angle, k)
                GotoPoint_out = app.GotoPoint_Angle(pos_x,pos_y,pos_z,angle, true);
                if(app.isConnected)
                    for id = app.DXL_IDS(1:4)
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_PROFILE_VELOCITY, app.VELOCITY);
                    end
                end

                if ~GotoPoint_out(1)
                    disp("Unable to reach position in a straight line");
                    return;
                end
                start_angles = deg2rad(app.read_all_current_pos());
                all_start_pos = app.fk_points(start_angles);
                p0 = all_start_pos(:,5);
                pT = [pos_x; pos_y; pos_z];

                waypoints = zeros(k, 3);
                for i = 1:k
                    s = i / k;
                    waypoints(i,:) = p0 + s*(pT - p0);
                end

                for i = 1:size(waypoints,1)
                    next_point = waypoints(i,:);
                    app.GotoPoint_Simple(next_point(1), next_point(2), next_point(3), angle);

                end
            end
                

            function [reached, time] = GotoPoint_Angle(app, pos_x, pos_y, pos_z, w_angle, test)
                if nargin < 6
                    test = false;
                end

                time = 0;

                reached = false;
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = app.L1_global;
                L2 = app.L2_global;
                L3 = app.L3_global;
                L4 = app.L4_global;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                phi = w_angle;
                wx = px - L4*cos(phi)*cos(theta1);
                wy = py - L4*cos(phi)*sin(theta1);
                wz = pz - L4*sin(phi);

                r = hypot(wx, wy);
                z = wz - L1;
        
                % --- 2R reachability ---
                D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);

                if abs(D) > 1
                    reached = false;
                    return;
                end

                D = max(min(D,1),-1);

                theta3_candidates = [atan2(sqrt(1-D^2), D),-atan2(sqrt(1-D^2), D)];
                theta3 = min(theta3_candidates);
                theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                theta4 = phi - theta2 - theta3;
        
                % ===== joint limits check (same as your setTheta clamp) =====
                % Convert to degrees in UI convention (before offset)
                th = [theta1 theta2 theta3 theta4];
                th_deg = rad2deg(th - app.thetaOffset);
    
                if th_deg(1) < app.theta_limit_deg(1,1) || th_deg(1) > app.theta_limit_deg(1,2),  return; end
                if th_deg(2) < app.theta_limit_deg(2,1) || th_deg(2) > app.theta_limit_deg(2,2),  return; end
                if th_deg(3) < app.theta_limit_deg(3,1) || th_deg(3) > app.theta_limit_deg(3,2),  return; end
                if th_deg(4) < app.theta_limit_deg(4,1) || th_deg(4) > app.theta_limit_deg(4,2),  return; end

                if(~test)
                    v_sync = zeros(1,4);
                    a_sync = zeros(1,4);

                    start_pos = app.read_all_current_pos();
                    distance = th_deg-start_pos;
                    abs_distance = abs(distance);
                    Dmax = max(abs_distance);
                    if Dmax < 1e-6
                        reached = true;
                        return
                    end

                    time = Dmax/app.VELOCITY_DEG+app.VELOCITY_DEG/app.ACCEL_DEG;
                    
                    for k = 1:numel(th_deg)
                        [v_sync(k), a_sync(k)] = app.ratio(Dmax, distance(k), app.VELOCITY_DEG, app.ACCEL_DEG);
                    end

                    app.set_group_v_a_deg(v_sync, a_sync);
                    app.set_group_theta(th_deg);

                    dt = 0.05;

                    q0 = start_pos(:)';      % 1x4
                    q1 = th_deg(:)';         % 1x4
                    dq = q1 - q0;            % 1x4

                    % 防止出现负速度/加速度或为0的情况
                    vmax = abs(v_sync(:)');  % 1x4
                    amax = abs(a_sync(:)');  % 1x4
                    sgn  = sign(dq);         % 1x4

                    % 计算每个关节的(ta, tc, T)
                    ta = zeros(1,4);   % accel time
                    tc = zeros(1,4);   % cruise time
                    Tj = zeros(1,4);   % total time

                    for k = 1:4
                        if abs(dq(k)) < 1e-9
                            ta(k) = 0; tc(k) = 0; Tj(k) = 0;
                            continue;
                        end

                        % 若 vmax 或 amax 给成 0，会导致无法规划，这里做兜底
                        if vmax(k) < 1e-9 && amax(k) < 1e-9
                            ta(k) = 0; tc(k) = 0; Tj(k) = 0;
                            continue;
                        elseif amax(k) < 1e-9
                            % 纯匀速（退化情况）
                            ta(k) = 0;
                            tc(k) = abs(dq(k)) / max(vmax(k), 1e-9);
                            Tj(k) = tc(k);
                            continue;
                        elseif vmax(k) < 1e-9
                            % 纯加减速（三角形且峰值速度由路程决定）
                            ta(k) = sqrt(abs(dq(k))/amax(k));
                            tc(k) = 0;
                            Tj(k) = 2*ta(k);
                            continue;
                        end

                        % 标准梯形判定：是否能跑到 vmax
                        ta_try = vmax(k)/amax(k);
                        d_acc  = 0.5*amax(k)*ta_try^2;   % 加速段位移（同减速段）
                        if 2*d_acc >= abs(dq(k))
                            % 跑不到 vmax -> 三角形
                            ta(k) = sqrt(abs(dq(k))/amax(k));
                            tc(k) = 0;
                            Tj(k) = 2*ta(k);
                            vmax(k) = amax(k)*ta(k); % 实际峰值速度
                        else
                            % 梯形
                            ta(k) = ta_try;
                            d_cru = abs(dq(k)) - 2*d_acc;
                            tc(k) = d_cru / vmax(k);
                            Tj(k) = 2*ta(k) + tc(k);
                        end
                    end

                    Tmax = max(Tj);
                    t = 0:dt:Tmax;

                    % 角度轨迹矩阵：size = [length(t), 4]
                    q_traj = zeros(numel(t), 4);

                    for i = 1:numel(t)
                        ti = t(i);
                        for k = 1:4
                            if Tj(k) <= 0
                                q_traj(i,k) = q1(k);
                                continue;
                            end

                            % 预先算一些段位移（用“正方向”算，最后乘 sgn）
                            d1 = 0.5*amax(k)*ta(k)^2;          % 加速段位移
                            d2 = vmax(k)*tc(k);                % 匀速段位移（若三角形则为0）

                            if ti <= ta(k)
                                % 加速段
                                d = 0.5*amax(k)*ti^2;
                            elseif ti <= (ta(k) + tc(k))
                                % 匀速段
                                d = d1 + vmax(k)*(ti - ta(k));
                            elseif ti <= (2*ta(k) + tc(k))
                                % 减速段
                                td = ti - (ta(k) + tc(k));
                                d  = d1 + d2 + vmax(k)*td - 0.5*amax(k)*td^2;
                            else
                                % 结束保持
                                d = abs(dq(k));
                            end

                            q_traj(i,k) = q0(k) + sgn(k)*d;
                        end

                        % 用你的 setTheta_sim 做动画（每个时间步刷新一次）
                        app.setTheta_sim(1, q_traj(i,1), []);
                        app.setTheta_sim(2, q_traj(i,2), []);
                        app.setTheta_sim(3, q_traj(i,3), []);
                        app.setTheta_sim(4, q_traj(i,4), []);
                        drawnow limitrate;   % 让UI及时刷新
                        pause(dt);
                        % pause(dt); % 如果你希望“真实时间播放”，取消注释；否则就是尽快跑完
                    end

                    disp('t(s)    q1(deg)   q2(deg)   q3(deg)   q4(deg)');
                    disp([t(:) q_traj]);
                end

                reached = true;
            end

            function GotoPoint_Simple(app, pos_x, pos_y, pos_z, w_angle)
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = app.L1_global;
                L2 = app.L2_global;
                L3 = app.L3_global;
                L4 = app.L4_global;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                phi = w_angle;
                wx = px - L4*cos(phi)*cos(theta1);
                wy = py - L4*cos(phi)*sin(theta1);
                wz = pz - L4*sin(phi);

                r = hypot(wx, wy);
                z = wz - L1;
        
                % --- 2R reachability ---
                D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);
                D = max(min(D,1),-1);

                theta3_candidates = [atan2(sqrt(1-D^2), D),-atan2(sqrt(1-D^2), D)];
                theta3 = min(theta3_candidates);
                theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                theta4 = phi - theta2 - theta3;
        
                % ===== joint limits check (same as your setTheta clamp) =====
                % Convert to degrees in UI convention (before offset)
                th = [theta1 theta2 theta3 theta4];
                th_deg = rad2deg(th - app.thetaOffset);
    
                if th_deg(1) < app.theta_limit_deg(1,1) || th_deg(1) > app.theta_limit_deg(1,2),  return; end
                if th_deg(2) < app.theta_limit_deg(2,1) || th_deg(2) > app.theta_limit_deg(2,2),  return; end
                if th_deg(3) < app.theta_limit_deg(3,1) || th_deg(3) > app.theta_limit_deg(3,2),  return; end
                if th_deg(4) < app.theta_limit_deg(4,1) || th_deg(4) > app.theta_limit_deg(4,2),  return; end

                app.set_group_theta(th_deg);

            end

            function put_angle = find_put_angle(app, pos_x, pos_y, pos_z)
                put_angle = app.GotoPoint(pos_x, pos_y, pos_z, app.phi_list_down, true);
                disp(put_angle);
            end


            function Jv = jacobian_pos(app, theta)
                % theta: 1x4 rad
                Tall = app.fk_T_all(theta);
            
                pe = Tall(1:3,4,5);      % end-effector position
                Jv = zeros(3,4);
            
                for i = 1:4
                    Ti_1 = Tall(:,:,i);          % T0_(i-1)
                    zi_1 = Ti_1(1:3,3);          % z-axis of frame (i-1) in base
                    pi_1 = Ti_1(1:3,4);          % origin of frame (i-1) in base
            
                    Jv(:,i) = cross(zi_1, (pe - pi_1));
                end
            end


            function T = dh_T(~, alpha, a, d, theta)
                % Standard DH (Craig):
                % T = RotZ(theta) * TransZ(d) * TransX(a) * RotX(alpha)
            
                ca = cos(alpha); sa = sin(alpha);
                ct = cos(theta); st = sin(theta);
            
                T = [ ct   -st*ca   st*sa   a*ct;
                      st    ct*ca  -ct*sa   a*st;
                      0       sa      ca      d;
                      0        0       0      1];
            end
            function Tall = fk_T_all(app, theta)
                % theta: 1x4 (rad) = [t1 t2 t3 t4]
                % returns Tall: 4x4x5, Tall(:,:,1)=I, Tall(:,:,k+1)=T0k
            
                % ---- Your DH table ----
                alpha = [deg2rad(90), 0, 0, 0];   % alpha0..alpha3
                a     = [0, app.L2_global, app.L3_global, app.L4_global]; % a0..a3
                d     = [app.L1_global, 0, 0, 0];         % d1..d4
            
                % ---- Joint angle offsets (rad) ----
                % 让第3关节零位时默认向下 90°：   % 若方向反了，把 -pi/2 改成 +pi/2
            
                % ensure theta is 1x4
                theta = reshape(theta, 1, []);
                if numel(theta) ~= 4
                    error("fk_T_all: theta must be 1x4 (rad).");
                end
            
                theta_eff = theta + app.thetaOffset;
            
                Tall = zeros(4,4,5);
                Tall(:,:,1) = eye(4);
            
                T = eye(4);
                for i = 1:4
                    Ti = app.dh_T(alpha(i), a(i), d(i), theta_eff(i));
                    T = T * Ti;
                    Tall(:,:,i+1) = T;
                end
            end


            function P = fk_points(app, theta)
                % Returns joint positions for plotting links
                % P: 3x5 (base + 4 joints/end-effector)
                Tall = app.fk_T_all(theta);
            
                P = zeros(3,5);
                for k = 1:5
                    P(:,k) = Tall(1:3,4,k);
                end
            end

            function p = fk_pos(app, theta)
                % theta: 1x4 rad
                Tall = app.fk_T_all(theta);
                p = Tall(1:3,4,5);  % T0_4 的平移（Tall(:,:,5)是末端）
            end


            function move_ee_const_speed(app, p_target, w_angle, v_const)
            % 末端以恒定线速度 v_const 从当前位置移动到目标点（每圈朝向目标）
            % 1~3：速度控制（雅可比速度法）
            % 4 ：位置控制保持腕角（工具平行地面）
            %
            % 仿真模式（app.isConnected==false）：
            %   每圈积分更新关节角 -> setTheta_sim() -> 自动 redraw + FK
            
                if nargin < 4 || isempty(v_const)
                    v_const = app.v_const_cart; % 你可在app里设默认恒速
                end

                reachable = ~isnan(app.GotoPoint(p_target(1), p_target(2), p_target(3), deg2rad(w_angle), true));
                fprintf("x: %.3f, y: %.3f, z: %.3f, angle: %.3f\n", p_target(1), p_target(2), p_target(3), w_angle);
                if ~reachable
                    disp("Can't reach current position");
                    return;
                end
            
                ids = app.DXL_IDS(1:4);
            
                % 参数
                lambda   = app.dls_lambda;
                qdot_max = app.qdot_max_degps;     % 建议 [.. .. .. ..] 或标量
                amax     = app.alpha_vel_degps2;   % deg/s^2
            
                % 只对 1~3 做速度平滑
                last_vel_123 = app.vel_last(1:3);
            
                t0 = tic;
            
                while true
                    % 读当前角度（deg）——你read_all_current_pos已支持仿真
                    q_deg = app.read_all_current_pos();  % 1x4 deg
            
                    % dt
                    dt = toc(t0);
                    dt = min(max(dt, 0.001), 0.05);
                    t0 = tic;
            
                    % 计算腕关节目标角（deg）
                    % 这里先用“当前q2 q3”算一个目标，后面仿真会用更新后的q2 q3再算一次更准
                    q4_des_deg_now = w_angle - q_deg(2) - q_deg(3);
            
                    % 用于FK/J的一致姿态（rad）
                    q = deg2rad(q_deg);
                    q(4) = deg2rad(q4_des_deg_now);
            
                    % 当前末端位置
                    p = fk_pos(app, q(:));  % 3x1
            
                    % 到目标的向量
                    d = (p_target(:) - p);
                    dist = norm(d);
            
                    % 退出条件：到位 + 速度很小
                    if dist < app.p_tolerance && all(abs(last_vel_123) < app.vel_tolerance)
                        break;
                    end
            
                    % =========================
                    % 恒定线速度的任务空间速度
                    % =========================
                    Kp = 5;
                    v_min = 0.01;
                    if dist <= app.p_tolerance
                        break;
                    else
                        v_prop = Kp*dist;
                        v_des = max(v_min, min(v_prop, v_const));
                        dir = d / dist;
                        p_dot_des = v_des * dir;
                    end
            
                    % Jacobian 3x4
                    Jv = jacobian_pos(app, q(:));
            
                    % ---- 固定姿态约束：降阶雅可比解 q1,q2,q3 ----
                    J1 = Jv(:,1); J2 = Jv(:,2); J3 = Jv(:,3); J4 = Jv(:,4);
                    Jr = [ J1, (J2 - J4), (J3 - J4) ];   % 3x3
            
                    A = (Jr*Jr' + (lambda^2)*eye(3));
                    qdot_r = Jr' * (A \ p_dot_des);      % 3x1 rad/s
            
                    % 关节速度（rad/s）
                    qdot = zeros(4,1);
                    qdot(1) = qdot_r(1);
                    qdot(2) = qdot_r(2);
                    qdot(3) = qdot_r(3);
                    qdot(4) = -qdot_r(2) - qdot_r(3);    % 仅用于一致性（硬件不下发4速度）
            
                    % rad/s -> deg/s
                    qdot_cmd_degps = rad2deg(qdot(:))';  % 1x4
            
                    % 速度限幅（建议你把1~3设小点）
                    if ~isempty(qdot_max)
                        if isscalar(qdot_max)
                            qdot_cmd_degps = max(min(qdot_cmd_degps, qdot_max), -qdot_max);
                        else
                            qdot_cmd_degps = max(min(qdot_cmd_degps, qdot_max(:)'), -qdot_max(:)');
                        end
                    end
            
                    % =========================
                    % 对 1~3 做加速度限幅平滑
                    % =========================
                    vel_123 = zeros(1,3);
                    for k = 1:3
                        v_des = qdot_cmd_degps(k);
                        dv = v_des - last_vel_123(k);
                        dv_lim = max(min(dv, amax*dt), -amax*dt);
                        vel_123(k) = last_vel_123(k) + dv_lim;
                    end
            
                    % =========================
                    % 分支：硬件 or 仿真
                    % =========================

                    [hit, jidx, q_next_deg] = will_hit_limit_next(app, q_deg, vel_123, dt, w_angle, 1.0);

                    if hit
                        fprintf("[ABORT] Next step will hit joint-%d limit. Motion cancelled.\n", jidx);
                    
                        % 实机：立刻停 1~3
                        if app.isConnected
                            for k = 1:3
                                write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), ...
                                    app.ADDR_PRO_GOAL_VELOCITY, 0);
                            end
                            % 4 继续保持当前/目标腕角（可选）
                        end
                    
                        % 仿真：不更新角度，直接退出
                        break;
                    end

                    if app.isConnected
                        
                        % --- 4号：位置保持 ---
                        q4_des_deg = w_angle - q_deg(2) - q_deg(3);
                        pos4_tick = deg2pos(app, q4_des_deg);
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(4), ...
                            app.ADDR_PRO_GOAL_POSITION, uint32(pos4_tick));
            
                        % --- 1~3号：速度控制 ---
                        for k = 1:3
                            vel_tick = degps2velTick(app, vel_123(k));
                            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), ...
                                app.ADDR_PRO_GOAL_VELOCITY, uint32(vel_tick));
                        end
            
                    else
                        % --- 仿真：积分更新关节角（deg） ---
                        q_next_deg = q_deg;
                        q_next_deg(1) = q_next_deg(1) + vel_123(1) * dt;
                        q_next_deg(2) = q_next_deg(2) + vel_123(2) * dt;
                        q_next_deg(3) = q_next_deg(3) + vel_123(3) * dt;
            
                        % 用更新后的 q2,q3 再算一次腕角（更一致）
                        q_next_deg(4) = w_angle - q_next_deg(2) - q_next_deg(3);
            
                        % 用你的 setTheta_sim 来驱动仿真（内部会 redraw -> FK+画图）
                        for idx = 1:4
                            setTheta_sim(app, idx, q_next_deg(idx), []);
                        end
                    end
            
                    % 记录
                    last_vel_123 = vel_123;
                    app.vel_last = [last_vel_123, 0];
            
                    pause(0.005);
                end
            
                % 停止：硬件才需要清速度
                if app.isConnected
                    for k = 1:3
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), ...
                            app.ADDR_PRO_GOAL_VELOCITY, 0);
                    end
                end
            
                app.vel_last = zeros(1,4);
                disp("Const-speed move complete (sim via setTheta_sim / hw via velocity+position).");
            end

            function [hit, hit_idx, q_next_deg] = will_hit_limit_next(app, q_deg, vel_123, dt, c, margin_deg)
            % 预测下一步是否会触碰/超过关节限位
            % q_deg: 1x4 当前关节角(deg)
            % vel_123: 1x3 本圈准备执行的关节速度(deg/s)（已经过限幅/平滑）
            % dt: 本圈步长(s)
            % c: 平行地面常数（deg），q4 = c - q2 - q3
            % margin_deg: 安全裕度（deg），比如 1~3 度，避免贴边抖动
            
                if nargin < 6 || isempty(margin_deg)
                    margin_deg = 1.0;
                end
            
                q_next_deg = q_deg;
            
                % 1~3 用速度积分预测
                q_next_deg(1) = q_next_deg(1) + vel_123(1) * dt;
                q_next_deg(2) = q_next_deg(2) + vel_123(2) * dt;
                q_next_deg(3) = q_next_deg(3) + vel_123(3) * dt;
            
                % 4 按约束算
                q_next_deg(4) = c - q_next_deg(2) - q_next_deg(3);
            
                hit = false; hit_idx = 0;
            
                for i = 1:4
                    lo = app.theta_limit_deg(i,1) + margin_deg;
                    hi = app.theta_limit_deg(i,2) - margin_deg;
                    if q_next_deg(i) < lo || q_next_deg(i) > hi
                        hit = true;
                        hit_idx = i;
                        return;
                    end
                end
            end


            function pick_block(app, pos_x, pos_y, pos_z, w_angle)
                if nargin < 5
                    w_angle = app.phi_list_down;
                end

                unify_pos = 0.08;
                wait_pos_z = pos_z+0.05;
                u_pos_z = 0.12;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                
                % if(automatic_w_angle)
                %     w_angle = app.GotoPoint(pos_x, pos_y, wait_pos_z, app.phi_list_simple, true);
                %     disp(w_angle);
                % end

                app.GotoPoint(u_pos_x, u_pos_y, u_pos_z, app.phi_list_down);
                app.GotoPoint(pos_x, pos_y, wait_pos_z, [w_angle, app.phi_list_down]);
                gripper_release(app);
                pause(0.5);
                app.last_pick_angle = app.GotoPoint(pos_x, pos_y, pos_z, [w_angle, app.phi_list_down]);
                gripper_hold(app);
                pause(0.5);
                app.GotoPoint(pos_x, pos_y, wait_pos_z, [w_angle, app.phi_list_down]);
                app.GotoPoint(u_pos_x, u_pos_y, u_pos_z, app.phi_list_down);
                disp("Pick Block Endded");
            end

            function put_block(app, pos_x, pos_y, pos_z)
                unify_pos = 0.08;
                wait_pos_z = pos_z+0.05;
                
                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_z = 0.12;

                reachable = ~isnan(app.GotoPoint(pos_x, pos_y, pos_z, app.last_pick_angle, true));
                
                if reachable
                    w_angle = app.last_pick_angle;
                else
                    w_angle = app.phi_list_down;
                end
                GotoPoint(app, u_pos_x, u_pos_y, u_pos_z, w_angle);
                GotoPoint(app, pos_x, pos_y, wait_pos_z, [w_angle, app.phi_list_down]);
                GotoPoint(app, pos_x, pos_y, pos_z, w_angle);
                gripper_release(app);
                pause(0.5);
                GotoPoint(app, pos_x, pos_y, wait_pos_z, [w_angle, app.phi_list_down]);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z, w_angle);
                disp("Put Block Endded");
            end

            function pick_block_angle(app)

                app.GotoPoint(app.grid2pos(2), app.grid2pos(0), 0.1, deg2rad(-45));
                app.GotoPoint(app.grid2pos(7.3), app.grid2pos(0), 0.035, deg2rad(-20));
                gripper_release(app, true);
                pause(0.5);
                app.GotoPoint(app.grid2pos(8.5), app.grid2pos(0.2), 0.03, deg2rad(3));
                gripper_hold(app);
                pause(0.5);
                app.GotoPoint(app.grid2pos(8.5), app.grid2pos(0.2), 0.05, deg2rad(3));
                app.GotoPoint(app.grid2pos(7.5), app.grid2pos(0), 0.05, deg2rad(-5));
                app.GotoPoint(app.grid2pos(6), app.grid2pos(0), 0.1, deg2rad(-30));
                app.GotoPoint(app.grid2pos(2), app.grid2pos(0), 0.1, deg2rad(-45));
                disp("Pick Block Endded");
            end

            function pick_and_put(app)
                pick_angle = app.find_put_angle(grid2pos(app, app.X_PutEditField.Value), grid2pos(app, app.Y_PutEditField.Value), app.Z_PutEditField.Value);
                disp(pick_angle);
                pick_block(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value),app.Z_PickEditField.Value, pick_angle);
                pause(0.3);
                put_block(app, grid2pos(app, app.X_PutEditField.Value), grid2pos(app, app.Y_PutEditField.Value), app.Z_PutEditField.Value);
            end

            function vel_payload_u32 = degps2velTick(~, vel_degps)

                RPM_PER_UNIT = 0.229;   % rpm per velocity unit
                DEG_PER_RPM  = 6.0;     % deg/s per rpm
                MAX_UNIT     = 1023;    % safety clamp (model dependent)
            
                % ---- deg/s -> velocity unit (float) ----
                vel_rpm  = vel_degps / DEG_PER_RPM;
                vel_unit = vel_rpm / RPM_PER_UNIT;
            
                % ---- quantize to int32 ----
                v_i32 = int32(round(vel_unit));
            
                % ---- avoid dead zone (optional but useful) ----
                if v_i32 == 0 && vel_unit > 0
                    v_i32 = int32(1);
                elseif v_i32 == 0 && vel_unit < 0
                    v_i32 = int32(-1);
                end
            
                % ---- clamp (still int32) ----
                if v_i32 >  MAX_UNIT
                    v_i32 = int32( MAX_UNIT);
                elseif v_i32 < -MAX_UNIT
                    v_i32 = int32(-MAX_UNIT);
                end
            
                % ---- pack as uint32 payload (keep two's complement bits) ----
                vel_payload_u32 = typecast(v_i32, 'uint32');
            end


            function acc_tick = degps2accelTick(~, acc_degps2)
                ACC_REV_PER_MIN2_PER_UNIT = 214.577;  % Dynamixel spec
                DEG_PER_REV = 360.0;
                SEC_PER_MIN = 60.0;
                MAX_UNIT = 32767;   % Profile Acceleration register limit
            
                % deg/s^2 -> rev/min^2
                acc_rev_min2 = acc_degps2 / DEG_PER_REV * (SEC_PER_MIN^2);
            
                % rev/min^2 -> Dynamixel unit
                acc_unit = acc_rev_min2 / ACC_REV_PER_MIN2_PER_UNIT;
            
                acc_tick = int32(round(acc_unit));
                if(acc_tick == 0)
                    acc_tick = 1;
                end
                acc_tick = min(max(acc_tick, 0), MAX_UNIT);
            end

            function set_profile_velocity_degps(app, idx, vel)
                if(app.isConnected)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, idx, app.ADDR_PRO_PROFILE_VELOCITY, app.degps2velTick(vel));
                else
                    disp("Can't set profile velocity. Robot Not Connected")
                end
            end

            function set_profile_acceleration_degps2(app, idx, accel)
                if(app.isConnected)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, idx, app.ADDR_PRO_PROFILE_ACCELERATION, app.degps2accelTick(accel));
                else
                    disp("Can't set profile accel. Robot Not Connected")
                end
            end

            function current_pos = read_all_current_pos(app)
                ids = app.DXL_IDS(1:4);
                current_pos = zeros(1, numel(ids));
                if(app.isConnected)
                    for k = 1:numel(ids)
                        current_pos(k) = app.thetaSign(k)*pos2deg(app, read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_IDS(k), app.ADDR_PRO_PRESENT_POSITION));
                    end
                else
                    current_pos = app.theta_current(1:4);
                    %disp("Can't Read Current Position. Robot Not Connected")
                end
            end

            function pass_gate(app, gate)
                %gate(3) == 1 if going along x axis, otherwise gate(3) == 0

                gate_safety_room = 1.5;
                
                if gate(4) == 1
                    app.GotoPoint_k_points(app.grid2pos(gate(1)-gate(5)*gate_safety_room), app.grid2pos(gate(2)), gate(3), deg2rad(-45), 2);
                    pause(0.3);
                    app.GotoPoint_k_points(app.grid2pos(gate(1)+gate(5)*gate_safety_room), app.grid2pos(gate(2)), gate(3), deg2rad(-45), 2);
                    pause(0.5);
                elseif gate(4) == 0
                    app.GotoPoint_k_points(app.grid2pos(gate(1)), app.grid2pos(gate(2)-gate(5)*gate_safety_room), gate(3), deg2rad(-45), 2);
                    pause(0.3);
                    app.GotoPoint_k_points(app.grid2pos(gate(1)), app.grid2pos(gate(2)+gate(5)*gate_safety_room), gate(3), deg2rad(-45), 2);
                    pause(0.5);
                end
                
            end

            function pass_all_gates(app, tool_pos_x, tool_pos_y)
                tool_pos_z = 0.07;
                wait_pos_z = 0.11;
                unify_pos = 0.08;
                u_pos_x = tool_pos_x/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;
                u_pos_y = tool_pos_y/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;

                app.GotoPoint(u_pos_x, u_pos_y, wait_pos_z, deg2rad(-45));
                app.GotoPoint(tool_pos_x, tool_pos_y, wait_pos_z, deg2rad(-45));
                app.gripper_release();
                pause(0.5);
                app.GotoPoint(tool_pos_x, tool_pos_y, tool_pos_z, deg2rad(-45));
                gripper_hold(app);
                pause(0.5);
                app.GotoPoint(tool_pos_x, tool_pos_y, wait_pos_z, deg2rad(-45));
                disp("Pick Block Endded");
                gates = [4, -5.2, 0.1, 1, 1; 6, 2, 0.12, 0, 1; 4, 7, 0.1, 1, -1];
                for k = 1:numel(gates(:,1))
                    disp("Passing Gate: ");
                    disp(gates(k,:));
                    app.pass_gate(gates(k,:));
                end
            end

            function PickPen (app)
                tool_pos_x = app.grid2pos(6);
                tool_pos_y = app.grid2pos(6);
                tool_pos_z = 0.04;
                wait_pos_z = 0.045;
                unify_pos = 0.08;
                u_pos_x = tool_pos_x/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;
                u_pos_y = tool_pos_y/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;

                angle = deg2rad(-45);

                app.GotoPoint(u_pos_x, u_pos_y, wait_pos_z, app.phi_list_down);
                app.GotoPoint(tool_pos_x, tool_pos_y, wait_pos_z, angle);
                app.gripper_release();
                pause(0.5);
                app.GotoPoint(tool_pos_x, tool_pos_y, tool_pos_z, angle);
                gripper_hold(app);
                pause(0.5);
                app.GotoPoint(tool_pos_x, tool_pos_y, wait_pos_z, angle);
                app.GotoPoint(u_pos_x, u_pos_y, wait_pos_z, app.phi_list_down);
                disp("Pick Pen Endded"); 
            end

            function ReturnPen (app)
                tool_pos_x = app.grid2pos(6);
                tool_pos_y = app.grid2pos(6);
                tool_pos_z = 0.04;
                wait_pos_z = 0.045;
                unify_pos = 0.08;
                u_pos_x = tool_pos_x/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;
                u_pos_y = tool_pos_y/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;

                angle = deg2rad(-45);

                app.GotoPoint(u_pos_x, u_pos_y, wait_pos_z, app.phi_list_down);
                pause(0.5);
                app.GotoPoint(tool_pos_x, tool_pos_y, wait_pos_z, angle);
                app.GotoPoint(tool_pos_x, tool_pos_y, tool_pos_z, angle);
                app.gripper_release();
                pause(0.5);
                app.GotoPoint(tool_pos_x, tool_pos_y, wait_pos_z, angle);
                app.GotoPoint(u_pos_x, u_pos_y, wait_pos_z, app.phi_list_down);
                disp("Return Pen Endded"); 
            end

            function DrawStraightLine(app, p1, p2, z)
                % p1: [x1, y1]
                % p2: [x2, y2]
                
                z_wait = z + 0.05;
                angle = deg2rad(-90);

                p11 = app.grid2pos(p1(1));
                p12 = app.grid2pos(p1(2));
                p21 = app.grid2pos(p2(1));
                p22 = app.grid2pos(p2(2));
                            
                % Hover above start point
                app.GotoPoint(p11, p12, z_wait, angle);

                pause(0.3);
                
                % Touch down
                app.GotoPoint(p11, p12, z, angle);
                
                % Draw to end point
                app.GotoPoint_k_points(p21, p22, z, angle, 2);
                
                % Lift up
                app.GotoPoint(p21, p22, z_wait, angle);
                pause(0.3);
            end


            function DrawFu (app)
                app.PickPen();
                z_draw = 0.04;
                
                tool_pos_x = app.grid2pos(6);
                tool_pos_y = app.grid2pos(6);
                wait_pos_z = 0.11;
                unify_pos = 0.08;
                u_pos_x = tool_pos_x/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;
                u_pos_y = tool_pos_y/sqrt(tool_pos_x^2 + tool_pos_y^2)*unify_pos;

                angle = deg2rad(-45);

                app.GotoPoint(u_pos_x, u_pos_y, wait_pos_z, angle);

                pause(0.1);
                
                % Radical
                % Stroke 1: 横 (Dot)
                app.DrawStraightLine([4.530, -1.890], [4.530, -1.260], z_draw);

                pause(0.1);
                
                % Stroke 2: 横 (Horizontal)
                app.DrawStraightLine([5.160, -2.205], [5.160, -0.945], z_draw);
                
                pause(0.1);
                               
                % Stroke 3: 竖 (Vertical)
                app.DrawStraightLine([7.680, -1.575], [5.160, -1.575], z_draw);
                
                pause(0.1);

                % Stroke 4: 撇 (Left-falling)
                app.DrawStraightLine([7.050, -2.205], [5.790, -1.890], z_draw);
                
                pause(0.1);

                % Stroke 5: 点 (Dot)
                app.DrawStraightLine([6.420, -0.945], [5.790, -1.260], z_draw);

                pause(0.1);

                % 横+口
                % Stroke 6: 横 (Vertical)
                app.DrawStraightLine([4.530, -0.315], [4.530, 0.945], z_draw);
               
                pause(0.1);
                
                % Stroke 7: 竖 (Horizontal)
                app.DrawStraightLine([5.160, -0.315], [5.790, -0.315], z_draw);

                pause(0.1);

                % Stroke 8: 横 (Vertical)
                app.DrawStraightLine([5.160, -0.315], [5.160, 0.945], z_draw);
                
                pause(0.1);

                % Stroke 9: 竖 (Horizontal)
                app.DrawStraightLine([5.160, 0.945], [5.790, 0.945], z_draw);
                
                pause(0.1);

                % Stroke 10: 横 (Vertical)
                app.DrawStraightLine([5.790, 0.945], [5.790, -0.315], z_draw);

                pause(0.1);

                % 田                
                % Stroke 11: 竖 (Vertical)
                app.DrawStraightLine([6.420, -0.630], [6.420, 1.260], z_draw);
                
                pause(0.1);

                % Stroke 12: 横 (Horizontal)
                app.DrawStraightLine([7.680, -0.630], [6.420, -0.630], z_draw);
                
                pause(0.1);

                % Stroke 13: 横 (Horizontal)
                app.DrawStraightLine([7.680, 1.260], [6.420, 1.260], z_draw);
                
                pause(0.1);

                % Stroke 14: 竖 (Vertical)
                app.DrawStraightLine([7.680, -0.630], [7.680, 1.260], z_draw);
                
                pause(0.1);

                % Stroke 15: 竖 (Vertical)
                app.DrawStraightLine([7.050, -0.630], [7.050, 1.260], z_draw);
                
                pause(0.1);

                % Stroke 16: 横 (Horizontal)
                app.DrawStraightLine([7.680, 0.315], [6.420, 0.315], z_draw);

                pause(0.1);

                app.ReturnPen();
            end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                disp("Started Running");
                % 3D 视图初始化
                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');
                view(app.UIAxes, 3);
                xlabel(app.UIAxes,'X'); ylabel(app.UIAxes,'Y'); zlabel(app.UIAxes,'Z');
            
                % 合理的显示范围（单位 m）
                R = 0.130+0.124+0.126+0.10;
                xlim(app.UIAxes, [-R R]);
                ylim(app.UIAxes, [-R R]);
                zlim(app.UIAxes, [-R R]);


                app.UIAxes.NextPlot = 'add';  % 允许更新不重置
            
                % 先创建一个空的线对象
                app.hArmLine = plot3(app.UIAxes, nan, nan, nan, '-o', 'LineWidth', 2);
                app.hGripperTrace = plot3(app.UIAxes, nan, nan, nan, '-', 'LineWidth', 2, 'Color', 'r');
                app.gripperTraceData = [];
                app.hRealTrace = plot3(app.UIAxes, nan, nan, nan, '-', 'LineWidth', 2, 'Color', 'g');   % 绿色=真实轨迹
                app.realTraceData = [];


                % 初始角度
                app.Slider.Value = 0; app.EditField.Value = 0;
                app.Slider2.Value = 0; app.EditField2.Value = 0;
                app.Slider3.Value = 0; app.EditField3.Value = 0;
                app.Slider4.Value = 0; app.EditField4.Value = 0;
                app.Slider5.Value = 0; app.EditField5.Value = 0;
                app.X_GridEditField.Value = 4; app.Y_GridEditField.Value = 4; app.PointZEditField.Value = 0.1;
                app.theta_current = [0,0,0,0,0];
                
                app.AngleWEditField.Value = -90;
                app.X_PickEditField.Value = 7;
                app.Y_PickEditField.Value = 0;
                app.Z_PickEditField.Value = 0.035;

                app.X_PutEditField.Value = 7;
                app.Y_PutEditField.Value = 7;
                app.Z_PutEditField.Value = 0.035;

                app.DXL_IDS = [app.DXL_ID1 app.DXL_ID2 app.DXL_ID3 app.DXL_ID4 app.DXL_GRIP];
                app.phi_list_down   = deg2rad(-83:1:-30);
                app.phi_list_simple = deg2rad([-83 0]);

                app.updateTimer = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period',0.05, ...          % 0.05s = 20Hz
                'TimerFcn',@(~,~)app.onTimerTick());
        
                app.redraw();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disconnectDxl(app);
            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.ThetaControlChanged(1, app.Slider.Value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.ThetaSliderChanging(event, 1);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.ThetaControlChanged(1, app.EditField.Value);
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.ThetaControlChanged(2, event.Value);
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            app.ThetaSliderChanging(event, 2);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            app.ThetaControlChanged(2, event.Value);
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            app.ThetaControlChanged(3, event.Value);
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            app.ThetaSliderChanging(event, 3);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            app.ThetaControlChanged(3, event.Value);
        end

        % Value changed function: Slider4
        function Slider4ValueChanged(app, event)
            app.ThetaControlChanged(4, event.Value);
        end

        % Value changing function: Slider4
        function Slider4ValueChanging(app, event)
            app.ThetaSliderChanging(event, 4);
        end

        % Value changed function: EditField4
        function EditField4ValueChanged(app, event)
            app.ThetaControlChanged(4, event.Value);
        end

        % Value changed function: Slider5
        function Slider5ValueChanged(app, event)
            app.ThetaControlChanged(5, event.Value);
        end

        % Value changing function: Slider5
        function Slider5ValueChanging(app, event)
            app.ThetaSliderChanging(event, 5);
        end

        % Value changed function: EditField5
        function EditField5ValueChanged(app, event)
            app.ThetaControlChanged(5, event.Value);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            connectDxl(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            disconnectDxl(app);
        end

        % Button pushed function: GotoPointButton
        function GotoPointButtonPushed(app, event)
            GotoPoint_k_points(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value, deg2rad(app.AngleWEditField.Value), 2);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            drawSquare(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.gripperTraceData = [];

            % 清空红色轨迹线（但不影响视角/坐标轴）
            if ~isempty(app.hGripperTrace) && isvalid(app.hGripperTrace)
                set(app.hGripperTrace, 'XData', nan, 'YData', nan, 'ZData', nan);
                app.realTraceData = [];
                set(app.hRealTrace, 'XData', nan, 'YData', nan, 'ZData', nan);
            end
            
        end

        % Button pushed function: GripperHoldButton
        function GripperHoldButtonPushed(app, event)
            gripper_hold(app);
        end

        % Button pushed function: GripperReleaseButton
        function GripperReleaseButtonPushed(app, event)
            gripper_release(app);
        end

        % Button pushed function: GotowithAngleButton
        function GotowithAngleButtonPushed(app, event)
            GotoPoint(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value, deg2rad(app.AngleWEditField.Value));
        end

        % Button pushed function: PickButton
        function PickButtonPushed(app, event)
            pick_block(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value), app.Z_PickEditField.Value);
        end

        % Button pushed function: PutButton
        function PutButtonPushed(app, event)
            put_block(app, grid2pos(app, app.X_PutEditField.Value), grid2pos(app, app.Y_PutEditField.Value), app.Z_PutEditField.Value);
        end

        % Button pushed function: PickandPutButton
        function PickandPutButtonPushed(app, event)
            app.pick_and_put();
        end

        % Button pushed function: PickwithAngleButton
        function PickwithAngleButtonPushed(app, event)
            app.pick_block_angle();
        end

        % Button pushed function: PositionModeButton
        function PositionModeButtonPushed(app, event)
            set_position_mode(app)
        end

        % Button pushed function: VelocityModeButton
        function VelocityModeButtonPushed(app, event)
            set_velocity_mode(app)
        end

        % Button pushed function: ReadCurrentPosButton
        function ReadCurrentPosButtonPushed(app, event)
            theta_all = app.read_all_current_pos();
            disp("Position Get");
            disp(theta_all);
        end

        % Button pushed function: JacobianButton
        function JacobianButtonPushed(app, event)
            app.move_ee_const_speed([grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value], app.AngleWEditField.Value)
        end

        % Button pushed function: PassGatesButton
        function PassGatesButtonPushed(app, event)
            app.pass_all_gates(app.grid2pos(app.X_GridEditField.Value), app.grid2pos(app.Y_GridEditField.Value));
        end

        % Button pushed function: PickPenButton
        function PickPenButtonPushed(app, event)
            app.PickPen();
        end

        % Button pushed function: ReturnPenButton
        function ReturnPenButtonPushed(app, event)
            app.ReturnPen();
        end

        % Button pushed function: DrawFuButton
        function DrawFuButtonPushed(app, event)
            app.DrawFu();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-120 120];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-90 90];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-90 90];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [-115 105];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged, true);
            app.Slider4.ValueChangingFcn = createCallbackFcn(app, @Slider4ValueChanging, true);
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.ValueChangedFcn = createCallbackFcn(app, @EditField4ValueChanged, true);
            app.Slider5Label = uilabel(app.UIFigure);
            app.Slider5Label.Text = 'Slider5';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [-90 90];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged, true);
            app.Slider5.ValueChangingFcn = createCallbackFcn(app, @Slider5ValueChanging, true);
            app.EditField5Label = uilabel(app.UIFigure);
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.ValueChangedFcn = createCallbackFcn(app, @EditField5ValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GotoPointButton = uibutton(app.UIFigure, 'push');
            app.GotoPointButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPointButtonPushed, true);
            app.GotoPointButton.Text = 'Go to Point';
            app.X_GridEditFieldLabel = uilabel(app.UIFigure);
            app.X_GridEditFieldLabel.Text = 'X_Grid';
            app.X_GridEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_GridEditFieldLabel = uilabel(app.UIFigure);
            app.Y_GridEditFieldLabel.Text = 'Y_Grid';
            app.Y_GridEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointZEditFieldLabel = uilabel(app.UIFigure);
            app.PointZEditFieldLabel.Text = 'PointZ';
            app.PointZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.GripperHoldButton = uibutton(app.UIFigure, 'push');
            app.GripperHoldButton.ButtonPushedFcn = createCallbackFcn(app, @GripperHoldButtonPushed, true);
            app.GripperHoldButton.Text = 'GripperHold';
            app.GripperReleaseButton = uibutton(app.UIFigure, 'push');
            app.GripperReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @GripperReleaseButtonPushed, true);
            app.GripperReleaseButton.Text = 'GripperRelease';
            app.AngleWEditFieldLabel = uilabel(app.UIFigure);
            app.AngleWEditFieldLabel.Text = 'AngleW';
            app.AngleWEditField = uieditfield(app.UIFigure, 'numeric');
            app.GotowithAngleButton = uibutton(app.UIFigure, 'push');
            app.GotowithAngleButton.ButtonPushedFcn = createCallbackFcn(app, @GotowithAngleButtonPushed, true);
            app.GotowithAngleButton.Text = 'Go to with Angle';
            app.X_PickEditFieldLabel = uilabel(app.UIFigure);
            app.X_PickEditFieldLabel.Text = 'X_Pick';
            app.X_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_PickEditFieldLabel = uilabel(app.UIFigure);
            app.Y_PickEditFieldLabel.Text = 'Y_Pick';
            app.Y_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_PickEditFieldLabel = uilabel(app.UIFigure);
            app.Z_PickEditFieldLabel.Text = 'Z_Pick';
            app.Z_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_PutEditFieldLabel = uilabel(app.UIFigure);
            app.X_PutEditFieldLabel.Text = 'X_Put';
            app.X_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_PutEditFieldLabel = uilabel(app.UIFigure);
            app.Y_PutEditFieldLabel.Text = 'Y_Put';
            app.Y_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_PutEditFieldLabel = uilabel(app.UIFigure);
            app.Z_PutEditFieldLabel.Text = 'Z_Put';
            app.Z_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.PickButton = uibutton(app.UIFigure, 'push');
            app.PickButton.ButtonPushedFcn = createCallbackFcn(app, @PickButtonPushed, true);
            app.PickButton.Text = 'Pick';
            app.PutButton = uibutton(app.UIFigure, 'push');
            app.PutButton.ButtonPushedFcn = createCallbackFcn(app, @PutButtonPushed, true);
            app.PutButton.Text = 'Put';
            app.PickandPutButton = uibutton(app.UIFigure, 'push');
            app.PickandPutButton.ButtonPushedFcn = createCallbackFcn(app, @PickandPutButtonPushed, true);
            app.PickandPutButton.Text = 'Pick and Put';
            app.PickwithAngleButton = uibutton(app.UIFigure, 'push');
            app.PickwithAngleButton.ButtonPushedFcn = createCallbackFcn(app, @PickwithAngleButtonPushed, true);
            app.PickwithAngleButton.Text = 'Pick with Angle';
            app.PositionModeButton = uibutton(app.UIFigure, 'push');
            app.PositionModeButton.ButtonPushedFcn = createCallbackFcn(app, @PositionModeButtonPushed, true);
            app.PositionModeButton.Text = 'Position Mode';
            app.VelocityModeButton = uibutton(app.UIFigure, 'push');
            app.VelocityModeButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityModeButtonPushed, true);
            app.VelocityModeButton.Text = 'Velocity Mode';
            app.ReadCurrentPosButton = uibutton(app.UIFigure, 'push');
            app.ReadCurrentPosButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCurrentPosButtonPushed, true);
            app.ReadCurrentPosButton.Text = 'ReadCurrentPos';
            app.JacobianButton = uibutton(app.UIFigure, 'push');
            app.JacobianButton.ButtonPushedFcn = createCallbackFcn(app, @JacobianButtonPushed, true);
            app.JacobianButton.Text = 'Jacobian';
            app.PassGatesButton = uibutton(app.UIFigure, 'push');
            app.PassGatesButton.ButtonPushedFcn = createCallbackFcn(app, @PassGatesButtonPushed, true);
            app.PassGatesButton.Text = 'Pass Gates';
            app.DrawFuButton = uibutton(app.UIFigure, 'push');
            app.DrawFuButton.ButtonPushedFcn = createCallbackFcn(app, @DrawFuButtonPushed, true);
            app.DrawFuButton.Text = 'DrawFu';
            app.PickPenButton = uibutton(app.UIFigure, 'push');
            app.PickPenButton.ButtonPushedFcn = createCallbackFcn(app, @PickPenButtonPushed, true);
            app.PickPenButton.Text = 'PickPen';
            app.ReturnPenButton = uibutton(app.UIFigure, 'push');
            app.ReturnPenButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnPenButtonPushed, true);
            app.ReturnPenButton.Text = 'ReturnPen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end